classdef fdn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadDataMenu                matlab.ui.container.Menu
        IndexfileMenu               matlab.ui.container.Menu
        PreloadedDataMenu           matlab.ui.container.Menu
        PublicDataMenu              matlab.ui.container.Menu
        AnalyzeMenu                 matlab.ui.container.Menu
        ChromatinpartitioningMenu   matlab.ui.container.Menu
        DifferentialexpressionMenu  matlab.ui.container.Menu
        DNfeatureanalysisMenu       matlab.ui.container.Menu
        LPmethodMenu_2              matlab.ui.container.Menu
        ABDiffExpfinderMenu         matlab.ui.container.Menu
        TADcallingMenu              matlab.ui.container.Menu
        VNEMenu_2                   matlab.ui.container.Menu
        VisualizeMenu               matlab.ui.container.Menu
        GenomewideMenu              matlab.ui.container.Menu
        HiCRNAseqMenu               matlab.ui.container.Menu
        ABswitchingMenu             matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SavedataMenu                matlab.ui.container.Menu
        ExportdataMenu              matlab.ui.container.Menu
        dataInfoMenu_2              matlab.ui.container.Menu
        HiCMenu_2                   matlab.ui.container.Menu
        RNAseqMenu_2                matlab.ui.container.Menu
        ExamplesMenu                matlab.ui.container.Menu
        LPmethodMenu                matlab.ui.container.Menu
        DNfeatureanalyzerMenu       matlab.ui.container.Menu
        VNEMenu                     matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        DocumentationMenu           matlab.ui.container.Menu
        TextArea_2                  matlab.ui.control.TextArea
        DNvestigatorLabel           matlab.ui.control.Label
    end

    properties (Access = public)
        dataInfo % data information structure
        H % Hi-C data structure
        R % RNA-seq data structure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% general start-up
            %clear
            %close all
            restoredefaultpath
            addpath(genpath('.'))
            rmpath(genpath('./functions/depreciated'))
            
        end

        % Menu selected function: IndexfileMenu
        function IndexfileMenuSelected(app, event)
            % text box with directions
            answer = questdlg('For this option, please select an excel or tab delimited file', ...
            'Load Data > Index File Info',...
            'Ok','Ok');
            
            % Process Input Data Information
            [app.dataInfo] = fdnLoadUserInput;
            
            % load Hi-C
            [app.H] = fdnLoadHic(app.dataInfo);
            
            % load RNA-seq
            [app.R] = fdnLoadRnaseq(app.dataInfo,app.H);
            
            % Confirm save (will overwrite data)
            f = app.UIFigure;
            msg = 'Saving these changes will overwrite previous changes.';
            title = 'Confirm Save';
            selection = uiconfirm(f,msg,title,...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Overwrite'
                    fdnSave(app.dataInfo,app.H,app.R);
                otherwise
            end
            
        end

        % Menu selected function: PreloadedDataMenu
        function PreloadedDataMenuSelected(app, event)
            % text box with directions
            answer = questdlg('For this option, please select a .mat file containing previously loaded data', ...
            'Load Data > Pre-loaded Data Info',...
            'Ok','Ok');
            
            %% select saved data file
            [file,path] = uigetfile('*.mat','Select saved file');
            
            % wait message
            waitBar = waitbar(0,'loading your saved data...','Name','Loading Data');
            set(findall(waitBar),'Units', 'normalized');    % Change the Units Property of the figure and all the children
            set(waitBar,'Position', [0.25 0.4 0.5 0.08]);
            
            % load data and overwrite app data structures
            load(fullfile(path,file))
            
            app.dataInfo = dataInfo;
            app.H = H;
            app.R = R;
            
            close(waitBar)
        end

        % Menu selected function: PublicDataMenu
        function PublicDataMenuSelected(app, event)
            % text box with directions
            answer = questdlg('For this option, please select from the list of samples you would like to analyze', ...
            'Load Data > Public Data Info',...
            'Ok','Ok');
            
            % select public data
            publicDataTable = readtable('publicDataIndex.xlsx');
            [indx,~] = listdlg('PromptString','Select Samples to analyze',...
                'ListString',publicDataTable.sample,...
                'ListSize',[500 500]);
            publicDataTable = publicDataTable(indx,:);
            
            % Process Input Data Information
            [app.dataInfo] = fdnLoadUserInput(publicDataTable);
            
            % load Hi-C
            [app.H] = fdnLoadHic(app.dataInfo);
        end

        % Menu selected function: ChromatinpartitioningMenu
        function ChromatinpartitioningMenuSelected(app, event)
            % set up dialog box
            prompt = {'Enter partition method:','Enter RNA-seq normalization:',...
                'Allow for chromosome arm split?','Plot results?','Partition split value:'};
            name = 'Input chromatin partitioning parameters';
            formats = struct('type', {}, 'style', {}, 'items', {}, ...
                'format', {}, 'limits', {}, 'size', {});
            
            % partition answers
            formats(1,1).type   = 'list';
            formats(1,1).style = 'popupmenu';
            formats(1,1).items = {'fiedler','pc1'};
            
            % RNA-seq normalization answers
            formats(2,1).type   = 'list';
            formats(2,1).style  = 'popupmenu';
            formats(2,1).items  = {'none','log2'};
            
            % Divide Chromosome answers
            formats(3,1).type   = 'list';
            formats(3,1).style = 'popupmenu';
            formats(3,1).items = {'no','yes'};
            
            % Plot answers
            formats(4,1).type   = 'list';
            formats(4,1).style = 'popupmenu';
            formats(4,1).items = [0,1];
            
            % Partition split value answers
            formats(5,1).type   = 'list';
            formats(5,1).style = 'popupmenu';
            formats(5,1).items = {'sign','ratio','bisection','gap'};
            
            % get user inputs parameters
            defaultanswer = {1, 1, 1, 1, 1};
            [answer, canceled] = inputsdlg(prompt, name, formats, defaultanswer);
            
            partParam.method = formats(1,1).items{answer{1}};
            partParam.rnaSeqNorm = formats(2,1).items{answer{2}};
            partParam.chrDivide = formats(3,1).items{answer{3}};
            partParam.plotFlag = formats(4,1).items(answer{4});
            partParam.fdvSplitMethod = formats(5,1).items{answer{5}};
            app.dataInfo.partParam = partParam;
            
            % run chromatin partitioning
            [app.H,app.R] = fdnChromPartition(app.dataInfo,app.H,app.R,partParam);
            
            % Confirm save (will overwrite data)
            f = app.UIFigure;
            msg = 'Saving these changes will overwrite previous changes.';
            title = 'Confirm Save';
            selection = uiconfirm(f,msg,title,...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Overwrite'
                    fdnSave(app.dataInfo,app.H,app.R);
                otherwise
            end
            
        end

        % Menu selected function: DifferentialexpressionMenu
        function DifferentialexpressionMenuSelected(app, event)
            % differential expression
            [app.R] = fdnDiffExpGsaa(app.dataInfo,app.R);
            
            % Confirm save (will overwrite data)
            f = app.UIFigure;
            msg = 'Saving these changes will overwrite previous changes.';
            title = 'Confirm Save';
            selection = uiconfirm(f,msg,title,...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Overwrite'
                    fdnSave(app.dataInfo,app.H,app.R);
                otherwise
            end
        end

        % Menu selected function: DNfeatureanalysisMenu
        function DNfeatureanalysisMenuSelected(app, event)
            
            % 4DN feature analyzer
            [features,score] = fdnSfAnalysis(app.dataInfo,app.R,app.H);
        end

        % Menu selected function: LPmethodMenu_2
        function LPmethodMenu_2Selected(app, event)
            % set up dialog box
            Title = 'Input LP analysis parameters';
            
            %%%% SETTING DIALOG OPTIONS
            % Options.WindowStyle = 'modal';
            Options.Resize = 'on';
            Options.Interpreter = 'tex';
            Options.CancelButton = 'on';
            Options.ApplyButton = 'on';
            Options.ButtonNames = {'Continue','Cancel'}; %<- default names, included here just for illustration
            Option.Dim = 1; % Horizontal dimension in fields
            
            Prompt = {};
            Formats = {};
            DefAns = struct([]);
            
            % Samples to compare
            Prompt(1,:) = {'Select Samples to compare','samples',[]};
            Formats(1,1).type   = 'list';
            Formats(1,1).style = 'listbox';
            Formats(1,1).format = 'text';
            Formats(1,1).items = app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            Formats(1,1).limits = [0 length(app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic')))];
            Formats(1,1).size = [140 80];
            DefAns(1).samples = app.dataInfo.sampleInfo.uniqueName(1:2)';
            
            % Select Gene to analyze
            Prompt(2,:) = {'Select gene region to analyze','gene',[]};
            Formats(2,1).type   = 'list';
            Formats(2,1).style = 'listbox';
            Formats(2,1).format = 'text';
            Formats(2,1).items = app.R.TPM.geneName;
            Formats(2,1).limits = [0 1];
            Formats(2,1).size = [140 200];
            DefAns.gene = app.R.TPM.geneName{1};
            
            % Input Binsize Flank
            Prompt(3,:) = {'Select bin flank size','flank',[]};
            Formats(3,1).type   = 'edit';
            Formats(3,1).format = 'integer';
            Formats(3,1).limits = [1 30];
            DefAns.flank = 20;
            
            % Input Alpha parameter
            Prompt(4,:) = {'Select \alpha parameter','alpha',[]};
            Formats(4,1).type   = 'edit';
            Formats(4,1).format = 'float';
            Formats(4,1).limits = [0 1];
            DefAns.alpha = .05;
            
            % Plot answers
            Prompt(5,:) = {'Plot Output' 'plotFlag',[]};
            Formats(5,1).type = 'check';
            DefAns.plotFlag = true;
            
            % get user inputs parameters
            [Answer,Cancelled] = inputsdlg(Prompt,Title,Formats,DefAns,Options);
            if Cancelled
                return
            end
            
            % Format answers
            goi = Answer.gene{1};
            goiFlank = Answer.flank;
            sampleSelect = app.dataInfo.sampleInfo.index(ismember(app.dataInfo.sampleInfo.uniqueName,Answer.samples));
            alphaParam = Answer.alpha;
            plotFlag = Answer.plotFlag;
            
            % extracting Hi-C ROIs to analyze
            goiChr = app.R.TPM.chr(ismember(app.R.TPM.geneName,goi),:);
            goiLoc = round(mean(find(cell2mat(cellfun(@sum, cellfun(@(x) contains(x,goi),...
                app.R.s100kb.geneTrim{app.R.TPM.chr(ismember(app.R.TPM.geneName,goi),:)},...
                'UniformOutput',false), 'UniformOutput',false)))));
            goiLocFlank = max([1, goiLoc-goiFlank]):min([size(app.H.s100kb.oeTrim{goiChr},1), goiLoc+goiFlank]);
            
            % extract ROI
            roiHOe = app.H.s100kb.oeTrim{goiChr}(goiLocFlank,goiLocFlank,sampleSelect);
            roiHOeL2 = log2(roiHOe);
            
            % set -Inf values to minimum
            tempRoiH = roiHOeL2;
            tempRoiH(isinf(tempRoiH)) = NaN;
            roiHOeL2(isinf(roiHOeL2)) = nanmin(tempRoiH(:));
            
            % Calculate the correlation matrices
            Hcorr = zeros(size(roiHOeL2));
            for iSample = 1:size(roiHOeL2,3)
                Hcorr(:,:,iSample) = corr(roiHOeL2(:,:,iSample));
            end
            
            % Perform the Larntz-Perlman procedure on these correlation matrices
            [H0,P,S,pval] = larntzPerlman(Hcorr,size(Hcorr,1),alphaParam,0);
            fprintf('H_0 = %i, p-val=%.4f...\n',H0,pval)
            
            % Identify regions in 99th percentile L-P regions
            if plotFlag
                % Identify regions in 99th percentile L-P regions
                tempXPrctile = 99;
                LPRegions = S > prctile(S(:),tempXPrctile);
                
                % create blue-white-red colormap based on min/max
                roiHLimAbs = min(abs([nanmin(tempRoiH(:)) nanmax(tempRoiH(:))]));
                roiHLim = [-roiHLimAbs roiHLimAbs];
                hicCMap = 1-((1-redblue(100))*.7);
                
                %% Figure
                figure('position',[100 500 1310 340])
                for iSample = 1:size(roiHOeL2,3)
                    
                    % Plot Hi-C
                    ax = subplot(1,size(roiHOeL2,3),iSample);
                    imagesc(roiHOeL2(:,:,iSample)), axis square
                    colormap(ax, hicCMap)
                    colorbar
                    caxis(roiHLim)
                    
                    if iSample==1; ylabel('log_2(O/E Hi-C)'); end
                    title(sprintf('%s', app.dataInfo.sampleInfo.uniqueName{iSample}), 'Interpreter', 'none')
                    
                    % Add circles around Larntz-Perlman ROIs
                    addROICircles(LPRegions,'green',1.5,4)
                end
                set(get(gcf,'children'),'linewidth',2,'fontsize',15)
                linkaxes(get(gcf,'children'))
                
                sgtitle(sprintf('Larntz-Perlman procedure, H_0=%i',H0))
            end
            
        end

        % Menu selected function: LPmethodMenu
        function LPmethodMenuSelected(app, event)
            answer = questdlg(sprintf('See: 4DNvestigator%sexampleScripts%slpExample.m',filesep,filesep), ...
            'LP Method Example Info',...
            'Ok','Ok');
        end

        % Menu selected function: GenomewideMenu
        function GenomewideMenuSelected(app, event)
            % select samples to compare
            [indx,tf] = listdlg('PromptString','Select Hi-C sample to plot:',...
                'SelectionMode','single',...
                'ListString',app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic')));
            
            % visualize
            figure, imagesc(log(app.H.s1mb.krTrim(:,:,indx))), axis square, hold on
            colormap(app.dataInfo.hicCMap);
            for iChr = 1:length(app.H.s1mb.chrStartTrim)
                plot([app.H.s1mb.chrStartTrim(1)-.5,app.H.s1mb.chrStartTrim(end)-.5],...
                    [app.H.s1mb.chrStartTrim(iChr)-.5 app.H.s1mb.chrStartTrim(iChr)-.5],'b-')
                plot([app.H.s1mb.chrStartTrim(iChr)-.5 app.H.s1mb.chrStartTrim(iChr)-.5],...
                    [app.H.s1mb.chrStartTrim(1)-.5,app.H.s1mb.chrStartTrim(end)-.5],'b-')
            end
            xticks(app.H.s1mb.chrStartTrim(1:22)), xticklabels(app.dataInfo.hicHeader.Chromosomes.chr(1:21))
            yticks(app.H.s1mb.chrStartTrim(1:22)), yticklabels(app.dataInfo.hicHeader.Chromosomes.chr(1:21))
            title(sprintf('Genome-wide Hi-C, %s',app.dataInfo.sampleInfo.uniqueName{indx}),'Interpreter','none')
            
            set(get(gcf,'children'),'linewidth',2,'fontsize',15)
        end

        % Menu selected function: HiCRNAseqMenu
        function HiCRNAseqMenuSelected(app, event)
            % select chromosome and resolution
            prompt = {'Select Sample:';'Select Chromosome:'};
            name = 'Input Hi-C RNA-seq visualization parameters';
            formats = struct('type', {}, 'style', {}, 'items', {}, ...
                'format', {}, 'limits', {}, 'size', {});
                
            % Select sample
            formats(1,1).type   = 'list';
            formats(1,1).style = 'popupmenu';
            formats(1,1).items = app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            
            % Select chromosome
            formats(2,1).type   = 'list';
            formats(2,1).style  = 'popupmenu';
            formats(2,1).items  = app.dataInfo.hicHeader.Chromosomes.chr;
            
            % get user inputs parameters
            defaultanswer = {1, 1};
            [answer, canceled] = inputsdlg(prompt, name, formats, defaultanswer);
            if canceled
                return
            end
            
            iSample = answer{1};
            iChr = answer{2};
            
            % get figure properties
            figure('position',[100 100 700 1000])
            
            % plot RNA-seq
            ax1 = subplot(6,1,1);
            rTemp = log2(app.R.s100kb.tpmMeanTrim{iChr}(:,iSample)+1);
            bar(rTemp), axis tight
            title(sprintf('%s, Chr%i',app.dataInfo.sampleInfo.uniqueName{iSample},iChr),'Interpreter','none')
            ylabel('log_2 TPM')
            
            % plot ABcomp
            if isfield(app.H.s100kb,'ABcomp')
                ax2 = subplot(6,1,2);
                tempAB = app.H.s100kb.ABcomp{iChr}(:,iSample);
                b = bar(tempAB,'FaceColor','flat','EdgeColor','none');
                b.CData(app.H.s100kb.groupIdx{iChr}(:,iSample)==1,:) = repmat([1 0 0],...
                    sum(app.H.s100kb.groupIdx{iChr}(:,iSample)==1),1);
                b.CData(app.H.s100kb.groupIdx{iChr}(:,iSample)==2,:) = repmat([0 1 0],...
                    sum(app.H.s100kb.groupIdx{iChr}(:,iSample)==2),1);
                axis tight
                ylabel(partParam.method)
            end
            
            % plot Hi-C
            ax3 = subplot(6,1,3:6);
            hTemp = app.H.s100kb.oeTrim{iChr}(:,:,iSample);
            climMain = [0 prctile(hTemp(:),90)];
            hTemp(hTemp>prctile(hTemp(:),99)) = prctile(hTemp(:),99);
            imagesc(hTemp), axis square
            colormap(ax3,app.dataInfo.hicCMap); %colorbar
            caxis(climMain)
            ylabel(sprintf('chr%i Hi-C map',iChr))
            
            % figure format
            set(get(gcf,'children'),'linewidth',2,'fontsize',15)
            linkaxes(get(gcf,'children'),'x')
        end

        % Menu selected function: ABDiffExpfinderMenu
        function ABDiffExpfinderMenuSelected(app, event)
            error('under development. Please see "visualize > A/B switching". Scott Ronquist, 3/15/19')
            
            % select samples to compare
            rnaseqSampleInfo = app.dataInfo.sampleInfo(strcmp(app.dataInfo.sampleInfo.dataType,'rnaseq'),:);
            rnaseqSampleInfoUnique = unique(strcat(rnaseqSampleInfo.dataType,rnaseqSampleInfo.sample,...
                num2str(rnaseqSampleInfo.timePoint)));
            [sampleSelect,tf] = listdlg('PromptString','Select Samples to compare:',...
                'SelectionMode','multiple',...
                'ListString',rnaseqSampleInfoUnique);
            
            % select chromosomes to compare
            [iChr,tf] = listdlg('PromptString','Select Samples to compare:',...
                'SelectionMode','single',...
                'ListString',app.dataInfo.hicHeader.Chromosomes.chr);
            
            % variables to be user input later
            varPercentile = 50;
            
            % determine where AB switch and plot results
            tempAB = app.H.s100kb.ABcomp{iChr}(:,sampleSelect);
            tempABVar = var(tempAB')';
            
            tempGroupIdx = (tempAB>0)+1;
            switchLocs = (~ismember(sum(tempAB<0,2),[0,length(sampleSelect)]) &...
                tempABVar > prctile(tempABVar(:),varPercentile))*max(abs(tempAB(:)));
            tempSwitchGenes{iChr} = unique(vertcat(app.R.s100kb.geneTrim{iChr}{logical(switchLocs)}));
            tempSwitchGenesAB{iChr} = app.R.s100kb.geneTrim{iChr}(logical(switchLocs));
            tempSwitchGenesAB{iChr} = [tempSwitchGenesAB{iChr},num2cell(find(logical(switchLocs))),...
                num2cell(tempAB(logical(switchLocs),:))];
            
            % figure plot AB all samples for a chromosome
            figure('position',[50 100 1500 1000])
            for iSample = 1:length(sampleSelect)
                subplot(length(sampleSelect),1,iSample)
                
                hold on, box on
                b = bar(switchLocs,'FaceColor','flat','EdgeColor','none');
                b.CData = [0 0 1];
                b = bar(-switchLocs,'FaceColor','flat','EdgeColor','none');
                b.CData = [0 0 1];
                
                b = bar(tempAB(:,iSample),'FaceColor','flat','EdgeColor','none');
                b.CData(tempGroupIdx(:,iSample)==1,:) = repmat([1 0 0],...
                    sum(tempGroupIdx(:,iSample)==1),1);
                b.CData(tempGroupIdx(:,iSample)==2,:) = repmat([0 1 0],...
                    sum(tempGroupIdx(:,iSample)==2),1);
                set(gca,'LineWidth',2,'FontSize',15)
                axis tight
                
                ylabel(sprintf('%s',rnaseqSampleInfoUnique{sampleSelect(iSample)}))
                
                if iSample == 1
                    title(sprintf('AB compartments, chr%d',iChr))
                end
            end
            
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            answer = questdlg('See: README.md, or visit https://github.com/scotronq/4DNvestigator', ...
            'Documentation Info',...
            'Ok','Ok');
        end

        % Menu selected function: TADcallingMenu
        function TADcallingMenuSelected(app, event)
            Title = 'Input TAD calling switching parameters';
            
            %%%% SETTING DIALOG OPTIONS
            % Options.WindowStyle = 'modal';
            Options.Resize = 'on';
            Options.Interpreter = 'tex';
            Options.CancelButton = 'on';
            Options.ApplyButton = 'on';
            Options.ButtonNames = {'Continue','Cancel'}; %<- default names, included here just for illustration
            Option.Dim = 4; % Horizontal dimension in fields
            
            Prompt = {};
            Formats = {};
            DefAns = struct([]);
            
            % Select Sample
            Prompt(1,:) = {'Select Sample', 'sampleSelect','(0, 100)'};
            Formats(1,1).type = 'list';
            Formats(1,1).format = 'text';
            Formats(1,1).style = 'popupmenu';
            Formats(1,1).items = app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            DefAns(1).sampleSelect = app.dataInfo.sampleInfo.uniqueName(1);
            
            % Select Chromosome
            Prompt(2,:) = {'Select Chromosome', 'chrSelect','(0, 100)'};
            Formats(2,1).type = 'list';
            Formats(2,1).format = 'integer';
            Formats(2,1).style = 'popupmenu';
            Formats(2,1).items = 1:22;
            DefAns.chrSelect = 1;
            
            % Fiedler number threshold
            Prompt(3,:) = {'Fiedler number threshold', 'fdnum','(0, 2)'};
            Formats(3,1).type = 'edit';
            Formats(3,1).format = 'float';
            Formats(3,1).limits = [0 2];
            Formats(3,1).size = 80;
            DefAns.fdnum = .8;
            
            % minimum TAD size
            Prompt(4,:) = {'minimum TAD size (100kb)', 'minTad','(0, 10)'};
            Formats(4,1).type = 'edit';
            Formats(4,1).format = 'integer';
            Formats(4,1).limits = [0 10];
            Formats(4,1).size = 80;
            DefAns.minTad = 3;
            
            
            % get user inputs parameters
            [params,Cancelled] = inputsdlg(Prompt,Title,Formats,DefAns,Options);
            if Cancelled
                return
            end
            
            %%%% SETTING DIALOG OPTIONS
            
            %% plot Hi-C with TADs
            hicCMap = [ones(64,1),[1:-1/63:0]',[1:-1/63:0]'];
            sampleSlice = find(ismember(app.dataInfo.sampleInfo.uniqueName,params.sampleSelect));
            tadBounds = TAD_Laplace_Sijia(double(app.H.s100kb.oeTrim{params.chrSelect}(:,:,sampleSlice)),params.fdnum, params.minTad);
            
            figure('position',[100 100 700 1000])
            
            hTemp = app.H.s100kb.krTrim{params.chrSelect}(:,:,sampleSlice);
            hTemp(hTemp>prctile(hTemp(:),99)) = prctile(hTemp(:),99);
            imagesc(log(hTemp)), axis square
            
            hold on
            for i = 2 : length(tadBounds)
                plot([tadBounds(i-1),tadBounds(i)],[tadBounds(i-1),tadBounds(i-1)],'b-','linewidth',2)
                plot([tadBounds(i-1),tadBounds(i)],[tadBounds(i),tadBounds(i)],'b-','linewidth',2)
                plot([tadBounds(i-1),tadBounds(i-1)],[tadBounds(i-1),tadBounds(i)],'b-','linewidth',2)
                plot([tadBounds(i),tadBounds(i)],[tadBounds(i-1),tadBounds(i)],'b-','linewidth',2)
            end
            
            title(sprintf('Sample: %s, Chr: %i [%.2f %i]',params.sampleSelect{1},params.chrSelect,...
                params.fdnum,params.minTad),'Interpreter','none')
            colormap(hicCMap); colorbar, caxis([0 1.5])%caxis(climMain)
            
            set(gca,'LineWidth',2,'FontSize',15)
            
        end

        % Menu selected function: ABswitchingMenu
        function ABswitchingMenuSelected(app, event)
            Title = 'Input A/B switching parameters';
            
            %%%% SETTING DIALOG OPTIONS
            % Options.WindowStyle = 'modal';
            Options.Resize = 'on';
            Options.Interpreter = 'tex';
            Options.CancelButton = 'on';
            Options.ApplyButton = 'on';
            Options.ButtonNames = {'Continue','Cancel'}; %<- default names, included here just for illustration
            Option.Dim = 4; % Horizontal dimension in fields
            
            Prompt = {};
            Formats = {};
            DefAns = struct([]);
            
            % Variance percentile
            Prompt(1,:) = {'Input Variance Percentile', 'varPerc','(0, 100)'};
            Formats(1,1).type = 'edit';
            Formats(1,1).format = 'float';
            Formats(1,1).limits = [0 100];
            Formats(1,1).size = 80;
            DefAns(1).varPerc = 90;
            
            % Display gene names in text
            Prompt(2,:) = {'display gene names in figure' 'geneNames',[]};
            Formats(2,1).type = 'check';
            DefAns.geneNames = false;
            
            % Select which Chrs to visualize
            Prompt(3,:) = {'Select which Chromosomes to visualize' 'numChr',[]};
            Formats(3,1).type = 'list';
            Formats(3,1).style = 'listbox';
            Formats(3,1).format = 'integer';
            Formats(3,1).items = 1:22;
            Formats(3,1).limits = [0 22]; % multi-select
            Formats(3,1).size = [40 320];
            DefAns.numChr = 1:22;
            
            % get user inputs parameters
            [params,Cancelled] = inputsdlg(Prompt,Title,Formats,DefAns,Options);
            %%%% SETTING DIALOG OPTIONS
            
            % Plot A/B compartments
            fdnAbSwitchPlot(app.dataInfo,app.H,app.R,params);
            
        end

        % Menu selected function: DNfeatureanalyzerMenu
        function DNfeatureanalyzerMenuSelected(app, event)
            answer = questdlg(sprintf('See: 4DNvestigator%sexampleScripts%sfeatureAnalyzerExample.m',filesep,filesep), ...
            '4DN feature analyzer Example Info',...
            'Ok','Ok');
        end

        % Menu selected function: VNEMenu
        function VNEMenuSelected(app, event)
            answer = questdlg(sprintf('See: 4DNvestigator%sexampleScripts%svneExample.m',filesep,filesep), ...
            'VNE Example Info',...
            'Ok','Ok');
        end

        % Menu selected function: RNAseqMenu_2
        function RNAseqMenuSelected(app, event)
            R = app.R;
            save('rnaseqData.mat','R')
            
            answer = questdlg('file "rnaseqData.mat" saved to current directory', ...
            'RNA-seq data export',...
            'Ok','Ok');
        end

        % Menu selected function: dataInfoMenu_2
        function dataInfoMenuSelected(app, event)
            dataInfo = app.dataInfo;
            save('dataInfo.mat','dataInfo')
            
            writetable(app.dataInfo.sampleInfo,'sampleInfo.txt')
            
            answer = questdlg('file "dataInfo.mat" and "sampleInfo.txt" saved to current directory', ...
            'dataInfo data export',...
            'Ok','Ok');
        end

        % Menu selected function: HiCMenu_2
        function HiCMenuSelected(app, event)
            H = app.H;
            save('hicData.mat','H','-v7.3')
            
            answer = questdlg('file "hicData.mat" saved to current directory', ...
            'Hi-C data export',...
            'Ok','Ok');
        end

        % Menu selected function: VNEMenu_2
        function VNEMenu_2Selected(app, event)
            Title = 'Input VNE parameters';
            
            %%%% SETTING DIALOG OPTIONS
            % Options.WindowStyle = 'modal';
            Options.Resize = 'on';
            Options.Interpreter = 'tex';
            Options.CancelButton = 'on';
            Options.ApplyButton = 'on';
            Options.ButtonNames = {'Continue','Cancel'}; %<- default names, included here just for illustration
            Option.Dim = 4; % Horizontal dimension in fields
            
            Prompt = {};
            Formats = {};
            DefAns = struct([]);
            
            % Select Sample
            Prompt(1,:) = {'Select Sample', 'sampleSelect','(0, 100)'};
            Formats(1,1).type = 'list';
            Formats(1,1).format = 'text';
            Formats(1,1).style = 'popupmenu';
            Formats(1,1).items = app.dataInfo.sampleInfo.uniqueName(ismember(app.dataInfo.sampleInfo.dataType,'hic'));
            DefAns(1).sampleSelect = app.dataInfo.sampleInfo.uniqueName(1);
            
            % Select Chromosome
            Prompt(2,:) = {'Select Chromosome', 'chrSelect','(0, 100)'};
            Formats(2,1).type = 'list';
            Formats(2,1).format = 'integer';
            Formats(2,1).style = 'popupmenu';
            Formats(2,1).items = 1:22;
            DefAns.chrSelect = 1;
            
            % get user inputs parameters
            [params,Cancelled] = inputsdlg(Prompt,Title,Formats,DefAns,Options);
            if Cancelled
                return
            end
            
            % extract user selected Hi-C
            sampleSlice = find(ismember(app.dataInfo.sampleInfo.uniqueName,params.sampleSelect));
            HTrim = app.H.s100kb.oeTrim{params.chrSelect}(:,:,sampleSlice);
            
            % compute the log2, change -inf values to minimum
            HTrim = log2(HTrim);
            tempH = HTrim;
            tempH(tempH==-inf) = min(tempH(isfinite(tempH)));
            HTrim = tempH;
            
            % set VNE parameters
            preProcess = 'corr';
            
            % calculate VNE
            vnEntropy = hicVnEntropy(HTrim,[],[],preProcess);
            
            % figure
            figure('position',[50 50 600 500])
            hicCMap = 1-((1-redblue(100))*.7);
            
            imagesc(HTrim), axis square
            title(sprintf('%s, chr: %i, VNE: %.2f',params.sampleSelect{1}, params.chrSelect, vnEntropy),...
                'interpreter','none')
            colormap(hicCMap), caxis([-2 2]), colorbar
            ylabel('log_2(O/E)')
            
        end

        % Menu selected function: SavedataMenu
        function SavedataMenuSelected(app, event)
            % Confirm save (will overwrite data)
            f = app.UIFigure;
            msg = 'Saving these changes will overwrite previous changes.';
            title = 'Confirm Save';
            selection = uiconfirm(f,msg,title,...
                'Options',{'Overwrite','Cancel'},...
                'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Overwrite'
                    fdnSave(app.dataInfo,app.H,app.R);
                otherwise
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadDataMenu = uimenu(app.UIFigure);
            app.LoadDataMenu.Text = 'Load Data';
            app.IndexfileMenu = uimenu(app.LoadDataMenu);
            app.IndexfileMenu.MenuSelectedFcn = createCallbackFcn(app, @IndexfileMenuSelected, true);
            app.IndexfileMenu.Text = 'Index file';
            app.PreloadedDataMenu = uimenu(app.LoadDataMenu);
            app.PreloadedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PreloadedDataMenuSelected, true);
            app.PreloadedDataMenu.Text = 'Pre-loaded Data';
            app.PublicDataMenu = uimenu(app.LoadDataMenu);
            app.PublicDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PublicDataMenuSelected, true);
            app.PublicDataMenu.Text = 'Public Data';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.ChromatinpartitioningMenu = uimenu(app.AnalyzeMenu);
            app.ChromatinpartitioningMenu.MenuSelectedFcn = createCallbackFcn(app, @ChromatinpartitioningMenuSelected, true);
            app.ChromatinpartitioningMenu.Text = 'Chromatin partitioning';
            app.DifferentialexpressionMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialexpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferentialexpressionMenuSelected, true);
            app.DifferentialexpressionMenu.Text = 'Differential expression';
            app.DNfeatureanalysisMenu = uimenu(app.AnalyzeMenu);
            app.DNfeatureanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @DNfeatureanalysisMenuSelected, true);
            app.DNfeatureanalysisMenu.Text = '4DN feature analysis';
            app.LPmethodMenu_2 = uimenu(app.AnalyzeMenu);
            app.LPmethodMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LPmethodMenu_2Selected, true);
            app.LPmethodMenu_2.Text = 'LP method';
            app.ABDiffExpfinderMenu = uimenu(app.AnalyzeMenu);
            app.ABDiffExpfinderMenu.MenuSelectedFcn = createCallbackFcn(app, @ABDiffExpfinderMenuSelected, true);
            app.ABDiffExpfinderMenu.Text = 'A/B + DiffExp finder';
            app.TADcallingMenu = uimenu(app.AnalyzeMenu);
            app.TADcallingMenu.MenuSelectedFcn = createCallbackFcn(app, @TADcallingMenuSelected, true);
            app.TADcallingMenu.Text = 'TAD calling';
            app.VNEMenu_2 = uimenu(app.AnalyzeMenu);
            app.VNEMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VNEMenu_2Selected, true);
            app.VNEMenu_2.Text = 'VNE';
            app.VisualizeMenu = uimenu(app.UIFigure);
            app.VisualizeMenu.Text = 'Visualize';
            app.GenomewideMenu = uimenu(app.VisualizeMenu);
            app.GenomewideMenu.MenuSelectedFcn = createCallbackFcn(app, @GenomewideMenuSelected, true);
            app.GenomewideMenu.Text = 'Genome-wide';
            app.HiCRNAseqMenu = uimenu(app.VisualizeMenu);
            app.HiCRNAseqMenu.MenuSelectedFcn = createCallbackFcn(app, @HiCRNAseqMenuSelected, true);
            app.HiCRNAseqMenu.Text = 'Hi-C + RNA-seq';
            app.ABswitchingMenu = uimenu(app.VisualizeMenu);
            app.ABswitchingMenu.MenuSelectedFcn = createCallbackFcn(app, @ABswitchingMenuSelected, true);
            app.ABswitchingMenu.Text = 'A/B switching';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.SavedataMenu = uimenu(app.SaveMenu);
            app.SavedataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedataMenuSelected, true);
            app.SavedataMenu.Text = 'Save data';
            app.ExportdataMenu = uimenu(app.SaveMenu);
            app.ExportdataMenu.Text = 'Export data';
            app.dataInfoMenu_2 = uimenu(app.ExportdataMenu);
            app.dataInfoMenu_2.MenuSelectedFcn = createCallbackFcn(app, @dataInfoMenuSelected, true);
            app.dataInfoMenu_2.Text = 'dataInfo';
            app.HiCMenu_2 = uimenu(app.ExportdataMenu);
            app.HiCMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HiCMenuSelected, true);
            app.HiCMenu_2.Text = 'Hi-C';
            app.RNAseqMenu_2 = uimenu(app.ExportdataMenu);
            app.RNAseqMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RNAseqMenuSelected, true);
            app.RNAseqMenu_2.Text = 'RNA-seq';
            app.ExamplesMenu = uimenu(app.UIFigure);
            app.ExamplesMenu.Text = 'Examples';
            app.LPmethodMenu = uimenu(app.ExamplesMenu);
            app.LPmethodMenu.MenuSelectedFcn = createCallbackFcn(app, @LPmethodMenuSelected, true);
            app.LPmethodMenu.Text = 'LP method';
            app.DNfeatureanalyzerMenu = uimenu(app.ExamplesMenu);
            app.DNfeatureanalyzerMenu.MenuSelectedFcn = createCallbackFcn(app, @DNfeatureanalyzerMenuSelected, true);
            app.DNfeatureanalyzerMenu.Text = '4DN feature analyzer';
            app.VNEMenu = uimenu(app.ExamplesMenu);
            app.VNEMenu.MenuSelectedFcn = createCallbackFcn(app, @VNEMenuSelected, true);
            app.VNEMenu.Text = 'VNE';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Select "Load Data" from the Menu bar above to load and format new data, load previously-saved data, or load publicly available Hi-C datasets'};
            app.DNvestigatorLabel = uilabel(app.UIFigure);
            app.DNvestigatorLabel.Text = '4DNvestigator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fdn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end